classdef DoanTHPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NghimTab                     matlab.ui.container.Tab
        NTHCHNHPHNGPHPTNHLabel_2     matlab.ui.control.Label
        Image3_2                     matlab.ui.control.Image
        Image2_2                     matlab.ui.control.Image
        SlnlpEditField               matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        KtqunghimEditField           matlab.ui.control.NumericEditField
        KtqunghimEditFieldLabel      matlab.ui.control.Label
        ChnphngphptmDropDown         matlab.ui.control.DropDown
        ChnphngphptmDropDownLabel    matlab.ui.control.Label
        NhpsaischophpEditField       matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        Nhpkhongphnlynghimvd12hoc410EditField  matlab.ui.control.EditField
        Nhpkhongphnlynghimvd12hoc410EditFieldLabel  matlab.ui.control.Label
        NhpphngtrnhEditField         matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        ThchinButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        GiithiunhmTab                matlab.ui.container.Tab
        NTHCHNHPHNGPHPTNHLabel_3     matlab.ui.control.Label
        Image3_3                     matlab.ui.control.Image
        Image2_3                     matlab.ui.control.Image
        ChititthnhvinPanel           matlab.ui.container.Panel
        NhimvTextArea                matlab.ui.control.TextArea
        NhimvTextAreaLabel           matlab.ui.control.Label
        EmailEditField               matlab.ui.control.EditField
        EmailEditFieldLabel          matlab.ui.control.Label
        MSSVEditField                matlab.ui.control.EditField
        MSSVEditFieldLabel           matlab.ui.control.Label
        HvtnEditField                matlab.ui.control.EditField
        HvtnEditFieldLabel           matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        DanhschthnhvinListBox        matlab.ui.control.ListBox
        DanhschthnhvinListBoxLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ThchinButton
        function ThchinButtonPushed(app, event)
            % --- 1. LẤY DỮ LIỆU ĐẦU VÀO ---
            strFun = app.NhpphngtrnhEditField.Value; % Lấy chuỗi phương trình
            strRange = app.Nhpkhongphnlynghimvd12hoc410EditField.Value; % Lấy chuỗi khoảng
            tol = app.NhpsaischophpEditField.Value; % Lấy sai số
            method = app.ChnphngphptmDropDown.Value; % Lấy phương pháp

            % Xử lý khoảng phân ly (chuyển text sang số)
            range = str2num(strRange); %#ok<ST2NM> 
            if length(range) ~= 2
                uialert(app.UIFigure, 'Khoảng phân ly phải có 2 số (ví dụ: 1 2)', 'Lỗi nhập liệu');
                return;
            end
            a = range(1);
            b = range(2);
            
            % Khởi tạo biến kết quả
            root = NaN;
            iter = 0;
            
            % --- 2. XỬ LÝ TÍNH TOÁN THEO PHƯƠNG PHÁP ---
            try
                switch method
                    case 'Chia đôi'
                        % Tạo hàm f(x) từ chuỗi
                        f = str2func(['@(x) ' strFun]);
                        
                        % Kiểm tra điều kiện đầu vào
                        fa = f(a);
                        fb = f(b);
                        if fa * fb > 0
                            uialert(app.UIFigure, 'Hàm số không đổi dấu trong khoảng này (f(a)*f(b) > 0).', 'Lỗi thuật toán');
                            return;
                        end
                        
                        % Thuật toán chia đôi
                        while (b - a) > tol
                            iter = iter + 1;
                            c = (a + b) / 2;
                            fc = f(c);
                            if fc == 0 % Tìm thấy nghiệm chính xác
                                a = c; b = c;
                                break;
                            end
                            if fa * fc < 0
                                b = c;
                                % fb = fc; % Không cần thiết cho logic nhưng tối ưu
                            else
                                a = c;
                                fa = fc;
                            end
                            if iter > 10000, break; end % Tránh vòng lặp vô hạn
                        end
                        root = (a + b) / 2;

                    case 'Lặp'
                        % Lưu ý: Với phương pháp lặp đơn, người dùng cần nhập g(x) 
                        % sao cho x = g(x).
                        % Ví dụ tìm nghiệm x^3 - x - 1 = 0.
                        % Phương trình nhập vào nên là: (x+1)^(1/3)
                        
                        g = str2func(['@(x) ' strFun]);
                        x0 = (a + b) / 2; % Chọn x0 ở giữa khoảng
                        x_old = x0;
                        err = inf;
                        
                        while err > tol
                            iter = iter + 1;
                            x_new = g(x_old);
                            err = abs(x_new - x_old);
                            x_old = x_new;
                            
                            if iter > 10000 
                                uialert(app.UIFigure, 'Thuật toán không hội tụ sau 10000 lần lặp.', 'Cảnh báo');
                                break; 
                            end
                        end
                        root = x_old;

                    case 'Newton (tuyến tính)'
                        % Cần tính đạo hàm f'(x)
                        % Sử dụng Symbolic Math Toolbox để tính đạo hàm tự động
                        %syms x_sym;
                        f_sym = str2sym(strFun);
                        df_sym = diff(f_sym); % Tính đạo hàm
                        
                        % Chuyển về dạng function handle để tính toán số
                        f = matlabFunction(f_sym);
                        df = matlabFunction(df_sym);
                        
                        x0 = (a + b) / 2; % Chọn xấp xỉ đầu
                        x_old = x0;
                        err = inf;
                        
                        while err > tol
                            iter = iter + 1;
                            val_f = f(x_old);
                            val_df = df(x_old);
                            
                            if val_df == 0
                                uialert(app.UIFigure, 'Đạo hàm bằng 0, không thể chia.', 'Lỗi thuật toán');
                                return;
                            end
                            
                            x_new = x_old - val_f / val_df;
                            err = abs(x_new - x_old);
                            x_old = x_new;
                            
                            if iter > 10000, break; end
                        end
                        root = x_old;
                end
                
                % --- 3. HIỂN THỊ KẾT QUẢ ---
               % Kiểm tra nếu nghiệm là số phức hoặc vô cực (Inf/NaN)
                if ~isreal(root) || ~isfinite(root)
                    app.KtqunghimEditField.Value = 0; % Gán tạm về 0 hoặc giữ nguyên
                    app.SlnlpEditField.Value = iter;
                    uialert(app.UIFigure, ...
                        'Kết quả phân kỳ (Vô cùng) hoặc là số phức. Vui lòng kiểm tra lại hàm g(x) đầu vào cho phương pháp Lặp.', ...
                        'Kết quả không hợp lệ');
                else
                    % Nếu là số hợp lệ thì mới gán
                    app.KtqunghimEditField.Value = root;
                    app.SlnlpEditField.Value = iter;
                end
                
                % --- 4. VẼ ĐỒ THỊ ---
                % Tạo dữ liệu vẽ
                x_plot = linspace(a - 1, b + 1, 100); % Mở rộng khoảng vẽ một chút
                
                % Xử lý hàm số để vẽ (Lưu ý phương pháp Lặp nhập g(x) nên cần xử lý khác chút nếu muốn vẽ f(x))
                if strcmp(method, 'Lặp')
                     % Nếu là Lặp, ta vẽ y = x và y = g(x) để thấy giao điểm
                     g_plot_func = str2func(['@(x) ' strFun]);
                     y_plot = arrayfun(g_plot_func, x_plot);
                     plot(app.UIAxes, x_plot, y_plot, 'b-', x_plot, x_plot, 'r--');
                     legend(app.UIAxes, 'g(x)', 'y = x');
                     title(app.UIAxes, 'Đồ thị phương pháp lặp');
                else
                     % Các phương pháp khác vẽ f(x) và trục hoành
                     f_plot_func = str2func(['@(x) ' strFun]);
                     % Dùng arrayfun để đảm bảo hoạt động tốt với vector
                     y_plot = arrayfun(f_plot_func, x_plot); 
                     
                     plot(app.UIAxes, x_plot, y_plot, 'b-', [min(x_plot) max(x_plot)], [0 0], 'k--');
                     hold(app.UIAxes, 'on');
                     plot(app.UIAxes, root, 0, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                     hold(app.UIAxes, 'off');
                     legend(app.UIAxes, 'f(x)', 'Trục hoành', 'Nghiệm');
                     title(app.UIAxes, ['Đồ thị hàm số: ' strFun]);
                end
                grid(app.UIAxes, 'on');
                
            catch ME
                uialert(app.UIFigure, ['Có lỗi xảy ra: ' ME.message], 'Lỗi');
            end
        end

        % Value changed function: DanhschthnhvinListBox
        function DanhschthnhvinListBoxValueChanged(app, event)
            %value = app.DanhschthnhvinListBox.Value;
            % 1. Lấy tên người được chọn từ ListBox
            name = app.DanhschthnhvinListBox.Value;
        
            % 2. Khai báo biến lưu thông tin (Khởi tạo rỗng)
            mssv = '';
            email = '';
            tasks = '';
            imageFile = ''; 
        
            % 3. Kiểm tra tên và gán dữ liệu tương ứng
            switch name
                case 'Nguyễn Hồng Minh'
                    mssv = '23207010';
                    email = '23207010@student.hcmus.edu.vn';
                    tasks = sprintf('+ abc\n+ xyz'); 
                    imageFile = 'minh.jpg'; % Đảm bảo file ảnh có tên này
        
                case 'Nguyễn Minh Quân'
                    mssv = '23207016';
                    email = '23207016@student.hcmus.edu.vn';
                    tasks = sprintf('- kqwd\n- hsac');
                    imageFile = 'quan.jpg';
        
                case 'Nguyễn Hoàng Khang'
                    mssv = '23207006';
                    email = '23207006@student.hcmus.edu.vn';
                    tasks = sprintf('- mns\n- qusz');
                    imageFile = 'khang.jpg'; 
            end
        
            % 4. Hiển thị thông tin lên giao diện
            app.HvtnEditField.Value = name;       
            app.MSSVEditField.Value = mssv;      
            app.EmailEditField.Value = email;     
            app.NhimvTextArea.Value = tasks;      
        
            % 5. Hiển thị hình ảnh (Kiểm tra file tồn tại để tránh lỗi)
            if exist(imageFile, 'file')
                app.Image.ImageSource = imageFile;
            else
                app.Image.ImageSource = ''; % Xóa ảnh nếu không tìm thấy file
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ThchinButton = uibutton(app.NghimTab, 'push');
            app.ThchinButton.ButtonPushedFcn = createCallbackFcn(app, @ThchinButtonPushed, true);
            app.ThchinButton.Text = 'Thực hiện';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NghimTab, 'text');
            app.NhpphngtrnhEditField.Value = 'x^3-x-1';
            app.Nhpkhongphnlynghimvd12hoc410EditFieldLabel = uilabel(app.NghimTab);
            app.Nhpkhongphnlynghimvd12hoc410EditFieldLabel.Text = {'Nhập khoảng phân '; 'ly nghiệm'; 'ví dụ : "1 2" hoặc "4 10"'};
            app.Nhpkhongphnlynghimvd12hoc410EditField = uieditfield(app.NghimTab, 'text');
            app.Nhpkhongphnlynghimvd12hoc410EditField.Value = '1 2';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghimTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.NhpsaischophpEditField = uieditfield(app.NghimTab, 'numeric');
            app.NhpsaischophpEditField.Value = 0.001;
            app.ChnphngphptmDropDownLabel = uilabel(app.NghimTab);
            app.ChnphngphptmDropDownLabel.Text = 'Chọn phương pháp tìm';
            app.ChnphngphptmDropDown = uidropdown(app.NghimTab);
            app.ChnphngphptmDropDown.Items = {'Chia đôi', 'Lặp', 'Newton (tuyến tính)'};
            app.ChnphngphptmDropDown.Value = 'Chia đôi';
            app.KtqunghimEditFieldLabel = uilabel(app.NghimTab);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.NghimTab, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.NghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.NghimTab, 'numeric');
            app.Image2_2 = uiimage(app.NghimTab);
            app.Image2_2.ImageSource = 'logo-fetel.png';
            app.Image3_2 = uiimage(app.NghimTab);
            app.Image3_2.ImageSource = 'logoKHTN.png';
            app.NTHCHNHPHNGPHPTNHLabel_2 = uilabel(app.NghimTab);
            app.NTHCHNHPHNGPHPTNHLabel_2.Text = 'ĐỒ ÁN THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.DanhschthnhvinListBoxLabel = uilabel(app.GiithiunhmTab);
            app.DanhschthnhvinListBoxLabel.Text = 'Danh sách thành viên';
            app.DanhschthnhvinListBox = uilistbox(app.GiithiunhmTab);
            app.DanhschthnhvinListBox.Items = {'Nguyễn Hồng Minh', 'Nguyễn Minh Quân', 'Nguyễn Hoàng Khang'};
            app.DanhschthnhvinListBox.ValueChangedFcn = createCallbackFcn(app, @DanhschthnhvinListBoxValueChanged, true);
            app.DanhschthnhvinListBox.Value = 'Nguyễn Hồng Minh';
            app.ChititthnhvinPanel = uipanel(app.GiithiunhmTab);
            app.ChititthnhvinPanel.Title = 'Chi tiết thành viên';
            app.Image = uiimage(app.ChititthnhvinPanel);
            app.HvtnEditFieldLabel = uilabel(app.ChititthnhvinPanel);
            app.HvtnEditFieldLabel.Text = 'Họ và tên';
            app.HvtnEditField = uieditfield(app.ChititthnhvinPanel, 'text');
            app.MSSVEditFieldLabel = uilabel(app.ChititthnhvinPanel);
            app.MSSVEditFieldLabel.Text = 'MSSV';
            app.MSSVEditField = uieditfield(app.ChititthnhvinPanel, 'text');
            app.EmailEditFieldLabel = uilabel(app.ChititthnhvinPanel);
            app.EmailEditFieldLabel.Text = 'Email';
            app.EmailEditField = uieditfield(app.ChititthnhvinPanel, 'text');
            app.NhimvTextAreaLabel = uilabel(app.ChititthnhvinPanel);
            app.NhimvTextAreaLabel.Text = 'Nhiệm vụ';
            app.NhimvTextArea = uitextarea(app.ChititthnhvinPanel);
            app.Image2_3 = uiimage(app.GiithiunhmTab);
            app.Image2_3.ImageSource = 'logo-fetel.png';
            app.Image3_3 = uiimage(app.GiithiunhmTab);
            app.Image3_3.ImageSource = 'logoKHTN.png';
            app.NTHCHNHPHNGPHPTNHLabel_3 = uilabel(app.GiithiunhmTab);
            app.NTHCHNHPHNGPHPTNHLabel_3.Text = 'ĐỒ ÁN THỰC HÀNH PHƯƠNG PHÁP TÍNH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoanTHPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end